classdef DashBoardV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Dashboard                       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PortfoliosvalueVaRSliderLabel   matlab.ui.control.Label
        PortfoliosvalueVaRSlider        matlab.ui.control.Slider
        UpdategraphsButton              matlab.ui.control.Button
        InitialvaluesPanel              matlab.ui.container.Panel
        LongpositionportfolioEditFieldLabel  matlab.ui.control.Label
        LongpositionportfolioEditField  matlab.ui.control.NumericEditField
        ShortpositionportfolioLabel     matlab.ui.control.Label
        ShortpositionportfolioEditField  matlab.ui.control.NumericEditField
        NetpositionEditFieldLabel       matlab.ui.control.Label
        NetpositionEditField            matlab.ui.control.NumericEditField
        PortfolioValueVarEditField      matlab.ui.control.NumericEditField
        EllipseToleranceEditField       matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        SelectpositionDropDownLabel     matlab.ui.control.Label
        SelectpositionDropDown          matlab.ui.control.DropDown
        EllipsetoleranceLabel           matlab.ui.control.Label
        EllipsetoleranceSlider          matlab.ui.control.Slider
        NumberofsimulationsSpinnerLabel  matlab.ui.control.Label
        NumberofsimulationsSpinner      matlab.ui.control.Spinner
        TabGroup                        matlab.ui.container.TabGroup
        ActualvaluesTab                 matlab.ui.container.Tab
        LongpositionportfolioEditField_2Label  matlab.ui.control.Label
        ActualLongFinalEditField        matlab.ui.control.NumericEditField
        ShortpositionportfolioEditField_2Label  matlab.ui.control.Label
        ActualShortFinalEditField       matlab.ui.control.NumericEditField
        NetpositionEditField_2Label     matlab.ui.control.Label
        ActualNetFinalEditField         matlab.ui.control.NumericEditField
        ActualCashflowsTab              matlab.ui.container.Tab
        LongpositionmaturitiesEditFieldLabel  matlab.ui.control.Label
        LongpositionmaturitiesEditField  matlab.ui.control.NumericEditField
        ShortpositionmaturitiesEditFieldLabel  matlab.ui.control.Label
        ShortpositionmaturitiesEditField  matlab.ui.control.NumericEditField
        OpenedlongpositionsEditFieldLabel  matlab.ui.control.Label
        OpenedlongpositionsEditField    matlab.ui.control.NumericEditField
        OpenedshortpositionLabel        matlab.ui.control.Label
        OpenedshortpositionEditField    matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        Hist1                           matlab.ui.control.UIAxes
        S_ClosedCF                      matlab.ui.control.UIAxes
        S_NetCF                         matlab.ui.control.UIAxes
        PortfolioperformancePanel       matlab.ui.container.Panel
        ActualvalueEditFieldLabel       matlab.ui.control.Label
        ActualvalueEditField            matlab.ui.control.NumericEditField
        ExpectedvalueMillionUSDEditFieldLabel  matlab.ui.control.Label
        ExpectedvalueMillionUSDEditField  matlab.ui.control.NumericEditField
        ActualpercentileEditFieldLabel  matlab.ui.control.Label
        ActualpercentileEditField       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

   
    methods (Access = public)
        
        function [portfolio] = simulate1(app, n, t)
            param = struct();

            % Expected log-returns
            param.('E_LongRFRate') = 0.008;
            param.('E_ShortRFRate') = 0.008;
            param.('E_LongExcRateChng') = 0.004;
            param.('E_ShortExcRateChng') = 0.003;
            
            % Standard deviation
            param.('SD_LongRFRate') = 0.05;        %(1)
            param.('SD_ShortRFRate') = 0.05;       %(2)
            param.('SD_LongExcRateChng') = 0.05;   %(3)
            param.('SD_ShortExcRateChng') = 0.05;  %(4)
            
            % Correlation matrix
            param.('Correlations') = [ 1.0  0.7 -0.2 -0.1;
                                       0.7  1.0 -0.1 -0.2;
                                      -0.2 -0.1  1.0  0.8;
                                      -0.1 -0.2  0.8  1.0];
                                  
            % Covarianve matrix
            param.('Covariance') = corr2cov([param.SD_LongRFRate, param.SD_ShortRFRate, ...
                                             param.SD_LongExcRateChng, param.SD_ShortExcRateChng], ...
                                         param.Correlations);
            
            % Creation of portfolio variable
            portfolio = struct();
            portfolio.Long = struct();
            portfolio.Short = struct();
            
            portfolio.Long.Initial = app.LongpositionportfolioEditField.Value/1e6;
            portfolio.Short.Initial = app.ShortpositionportfolioEditField.Value/1e6;
            
            % Simulating cashflows
            Z = mvnrnd(t*[param.SD_LongRFRate, param.SD_ShortRFRate, ...
                param.SD_LongExcRateChng, param.SD_ShortExcRateChng], ...
                t*param.Covariance, n);
            
            % Calculating initial and final values
            portfolio.Long.Final = portfolio.Long.Initial*exp(Z(:,1)+Z(:,3));
            portfolio.Short.Final = portfolio.Short.Initial*exp(Z(:,2)+Z(:,4));
            portfolio.Net.Initial = portfolio.Long.Initial - portfolio.Short.Initial;
            portfolio.Net.Final = portfolio.Long.Final - portfolio.Short.Final;

            %VaR = quantile(portfolio.Net.Final,VaRp,'all');
            
            % Average portion assumption
            muMaturity = 1/11;
            maturityCF = muMaturity.*(1+(mvnrnd([0 0], [1 0; 0 1], n))*0.03);
            
            % Portfolio valuation
            portfolio.Long.Valuation = (portfolio.Long.Final-portfolio.Long.Initial).*(1-maturityCF(:,1));
            portfolio.Short.Valuation = (portfolio.Short.Final-portfolio.Short.Initial).*(1-maturityCF(:,2));
            portfolio.Net.Valuation = portfolio.Long.Valuation - portfolio.Short.Valuation ;
            
            % Matirity cashflows
            portfolio.Long.MaturityCF = maturityCF(:,1).*portfolio.Long.Final;
            portfolio.Short.MaturityCF = maturityCF(:,2).*portfolio.Short.Final;
            portfolio.Net.MaturityCF = portfolio.Long.MaturityCF - portfolio.Short.MaturityCF;
            
            % Opened position cashflows
            portfolio.Long.OpenCF = portfolio.Long.Final - (portfolio.Long.Initial + portfolio.Long.Valuation -...
                portfolio.Long.MaturityCF);
            portfolio.Short.OpenCF = portfolio.Short.Final - (portfolio.Short.Initial + portfolio.Short.Valuation -...
                portfolio.Short.MaturityCF);
            portfolio.Net.OpenCF = portfolio.Long.OpenCF - portfolio.Short.OpenCF;
            
            % Net cashflows
            portfolio.Long.NetCF = portfolio.Long.MaturityCF - portfolio.Long.OpenCF;
            portfolio.Short.NetCF = portfolio.Short.MaturityCF - portfolio.Short.OpenCF;
            portfolio.Net.NetCF = portfolio.Long.NetCF - portfolio.Short.NetCF;
        end
        
        function plotErrorEllipse1(app, X, Y, p, x1, y1)
            % plotErrorEllipse function plots X-Y and a confidence ellipse which
            % contains 100*(1-p)% of the pairs (x,y).    
            mu = mean([X Y]);
            Sigma = cov([X Y]);
            s = -2 * log(1 - p);
            [V, D] = eig(Sigma * s);
        
            t = linspace(0, 2 * pi);
            a = (V * sqrt(D)) * [cos(t(:))'; sin(t(:))'];
            
            s1 = scatter(app.S_ClosedCF, X, Y, 'filled');
            s1.MarkerFaceAlpha = 0.1;
            s1.MarkerFaceColor = [0.6 0.6 0.6];
            s1.SizeData = 20;
            %xlabel(app.S_ClosedCF, 'Valuation');
            %ylabel(app.S_ClosedCF, 'Maturity cashflows');
            hold(app.S_ClosedCF, "on");
            plot(app.S_ClosedCF, a(1, :) + mu(1), a(2, :) + mu(2), '--', 'Color', [0.6350 0.0780 0.1840]);
            if x1 ~= 0 && y1 ~= 0
                xline(app.S_ClosedCF, x1,'--', 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5);
                yline(app.S_ClosedCF, y1,'--', 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5);
                %scatter(app.S_ClosedCF, x1, y1, 'filled', "o", "MarkerFaceColor", [0.8500 0.3250 0.0980]);
            end
            hold(app.S_ClosedCF, "off");
        end
        
        function plotErrorEllipse2(app, X, Y, p, x1, y1)
            % plotErrorEllipse function plots X-Y and a confidence ellipse which
            % contains 100*(1-p)% of the pairs (x,y).    
            mu = mean([X Y]);
            Sigma = cov([X Y]);
            s = -2 * log(1 - p);
            [V, D] = eig(Sigma * s);
        
            t = linspace(0, 2 * pi);
            a = (V * sqrt(D)) * [cos(t(:))'; sin(t(:))'];
            
            s1 = scatter(app.S_NetCF, X, Y, 'filled');
            s1.MarkerFaceAlpha = 0.1;
            s1.MarkerFaceColor = [0.6 0.6 0.6];
            s1.SizeData = 20;
            %xlabel(app.S_ClosedCF, 'Valuation');
            %ylabel(app.S_ClosedCF, 'Maturity cashflows');
            hold(app.S_NetCF, "on");
            plot(app.S_NetCF, a(1, :) + mu(1), a(2, :) + mu(2), '--', 'Color', [0.6350 0.0780 0.1840]);
            if x1 ~= 0 && y1 ~= 0
                xline(app.S_NetCF, x1,'--', 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5);
                yline(app.S_NetCF, y1,'--', 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5);
                %scatter(app.S_NetCF, x1, y1, 'filled', "o", "MarkerFaceColor", [0.8500 0.3250 0.0980]);
            end
            hold(app.S_NetCF, "off");
        end
        
        function plotHist1(app, portfolioValue, bins, VaRp, VaR)
            h1 = histogram(app.Hist1, portfolioValue, bins);
            h1.Normalization = 'probability';
            h1.EdgeColor = 'w';
            h1.FaceColor = [0.6 0.6 0.6];
            hold(app.Hist1, 'on');
            xline(app.Hist1, VaR,'--',...
                {[sprintf('VaR %.1f', 100*VaRp) '%']},...
                'LabelHorizontalAlignment','left',...
                'Color', [0.6350 0.0780 0.1840]);
            if app.ActualvalueEditField.Value ~= 0
                xline(app.Hist1, app.ActualvalueEditField.Value/1e6, ...
                    '--', 'Actual value', 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5); 
                sortedX = sort(portfolioValue);
                listCumProb = (1/length(sortedX))*(0.5:1:length(sortedX)-0.5);
                percentile = interp1(sortedX,listCumProb,app.ActualvalueEditField.Value/1e6);
                app.ActualpercentileEditField.Value = 100*percentile;
            end
            hold(app.Hist1, 'off');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Dashboard.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {659, 659};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: UpdategraphsButton
        function GraphS1(app, event)
            global VaRp VaR ellipseP portValue portValuation closedCF netCF portfolio openCF
            VaRp = app.PortfoliosvalueVaRSlider.Value/100;
            ellipseP = app.EllipsetoleranceSlider.Value/100;
            VaR = quantile(portValue,VaRp,'all');
            app.ExpectedvalueMillionUSDEditField.Value = mean(portValue)*1e6;
            
            % We update the actual values from the dashboard
            portfolio.Long.ActualFinal = app.ActualLongFinalEditField.Value/1e6;
            portfolio.Short.ActualFinal = app.ActualShortFinalEditField.Value/1e6;
            portfolio.Net.ActualFinal = app.ActualNetFinalEditField.Value/1e6;
            
            portfolio.Long.ActualMaturityCF = app.LongpositionmaturitiesEditField.Value/1e6;
            portfolio.Short.ActualMaturityCF = app.ShortpositionmaturitiesEditField.Value/1e6;
            
            portfolio.Long.ActualOpenedCF = app.OpenedlongpositionsEditField.Value/1e6;
            portfolio.Short.ActualOpenedCF = app.OpenedshortpositionEditField.Value/1e6;
            
            portfolio.Long.ActualNetCF = portfolio.Long.ActualMaturityCF - portfolio.Long.ActualOpenedCF;
            portfolio.Short.ActualNetCF = portfolio.Short.ActualMaturityCF - portfolio.Short.ActualOpenedCF;
            
            portfolio.Net.ActualMaturityCF = portfolio.Long.ActualMaturityCF - portfolio.Short.ActualMaturityCF;
            portfolio.Net.ActualOpenedCF = portfolio.Long.ActualOpenedCF - portfolio.Short.ActualOpenedCF;
            portfolio.Net.ActualNetCF = portfolio.Long.ActualNetCF - portfolio.Short.ActualNetCF;
            
            % We define the actual values we are going to plot
            value = app.SelectpositionDropDown.Value;
            app.ActualvalueEditField.Value = portfolio.(value).ActualFinal*1e6;
            x11 = portfolio.(value).ActualFinal - portfolio.(value).Initial;
            y11 = portfolio.(value).ActualMaturityCF;
            y21 = portfolio.(value).ActualOpenedCF;
            
            % Auxiliar variable to define the cashflows sign
            if strcmp(value, 'Short')
                aux = 1;
            else
                aux = -1;
            end            

            plotHist1(app, portValue, 30, VaRp, VaR)
            plotErrorEllipse1(app, portValuation, closedCF*(-aux), ellipseP, x11, y11*(-aux));
            plotErrorEllipse2(app, portValuation, openCF*aux, ellipseP, x11, y21*aux);
        end

        % Value changed function: PortfoliosvalueVaRSlider
        function PortfoliosvalueVaRSliderValueChanged(app, event)
            global VaRp
            VaRp = app.PortfoliosvalueVaRSlider.Value/100;
            app.PortfolioValueVarEditField.Value = VaRp*100;
        end

        % Value changed function: EllipsetoleranceSlider
        function EllipsetoleranceSliderValueChanged(app, event)
            global ellipseP
            ellipseP = app.EllipsetoleranceSlider.Value/100;
            app.EllipseToleranceEditField.Value = ellipseP*100;
        end

        % Value changed function: PortfolioValueVarEditField
        function PortfolioValueVarEditFieldValueChanged(app, event)
            global VaRp
            VaRp = app.PortfolioValueVarEditField.Value/100;
            app.PortfoliosvalueVaRSlider.Value = VaRp*100;
        end

        % Value changed function: EllipseToleranceEditField
        function EllipseToleranceEditFieldValueChanged(app, event)
            global ellipseP
            ellipseP = app.EllipseToleranceEditField.Value/100;
            app.EllipsetoleranceSlider.Value = ellipseP*100;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            global VaRp portfolio portValue portValuation closedCF VaR netCF openCF
            VaRp = app.PortfoliosvalueVaRSlider.Value/100;
            n = app.NumberofsimulationsSpinner.Value;
            [portfolio] = simulate1(app, n, 1/12);
            value = app.SelectpositionDropDown.Value;
            portValue = portfolio.(value).Final;
            app.ExpectedvalueMillionUSDEditField.Value = mean(portValue)*1e6;
            portValuation = portfolio.(value).Final - portfolio.(value).Initial; %portfolio.(value).Valuation;
            closedCF = portfolio.(value).MaturityCF;
            netCF = portfolio.(value).NetCF;
            openCF = portfolio.(value).OpenCF;
            
            % We update the actual values from the dashboard
            portfolio.Long.ActualFinal = app.ActualLongFinalEditField.Value/1e6;
            portfolio.Short.ActualFinal = app.ActualShortFinalEditField.Value/1e6;
            portfolio.Net.ActualFinal = app.ActualNetFinalEditField.Value/1e6;
            
            portfolio.Long.ActualMaturityCF = app.LongpositionmaturitiesEditField.Value/1e6;
            portfolio.Short.ActualMaturityCF = app.ShortpositionmaturitiesEditField.Value/1e6;
            portfolio.Long.ActualOpenedCF = app.OpenedlongpositionsEditField.Value/1e6;
            portfolio.Short.ActualOpenedCF = app.OpenedshortpositionEditField.Value/1e6;
            
            portfolio.Long.ActualNetCF = portfolio.Long.ActualMaturityCF - portfolio.Long.ActualOpenedCF;
            portfolio.Short.ActualNetCF = portfolio.Short.ActualMaturityCF - portfolio.Short.ActualOpenedCF;
            
            portfolio.Net.ActualMaturityCF = portfolio.Long.ActualMaturityCF - portfolio.Short.ActualMaturityCF;
            portfolio.Net.ActualOpenedCF = portfolio.Long.ActualOpenedCF - portfolio.Short.ActualOpenedCF;
            portfolio.Net.ActualNetCF = portfolio.Long.ActualNetCF - portfolio.Short.ActualNetCF;
            
            app.ActualvalueEditField.Value = portfolio.(value).ActualFinal*1e6;
            
            % We define the actual values we are going to plot
            value = app.SelectpositionDropDown.Value;
            x11 = portfolio.(value).ActualFinal - portfolio.(value).Initial;
            y11 = portfolio.(value).ActualMaturityCF;
            y21 = portfolio.(value).ActualOpenedCF;
            
            % Auxiliar variable to define the cashflows sign
            if strcmp(value, 'Short')
                aux = 1;
            else
                aux = -1;
            end
            
            ellipseP = app.EllipsetoleranceSlider.Value/100;
            VaR = quantile(portValue,VaRp,'all');
            plotHist1(app, portValue, 30, VaRp, VaR)
            plotErrorEllipse1(app, portValuation, closedCF*(-aux), ellipseP, x11, y11*(-aux));
            plotErrorEllipse2(app, portValuation, openCF*aux, ellipseP, x11, y21*aux);
        end

        % Value changed function: SelectpositionDropDown
        function SelectpositionDropDownValueChanged(app, event)
            value = app.SelectpositionDropDown.Value;
            global portfolio portValue portValuation closedCF netCF openCF
            portValue = portfolio.(value).Final;
            portValuation = portfolio.(value).Final - portfolio.(value).Initial; %portfolio.(value).Valuation;
            closedCF = portfolio.(value).MaturityCF;
            netCF = portfolio.(value).NetCF;
            openCF = portfolio.(value).OpenCF;
            app.ExpectedvalueMillionUSDEditField.Value = mean(portValue)*1e6;
        end

        % Value changed function: LongpositionportfolioEditField
        function LongpositionportfolioEditFieldValueChanged(app, event)
            global portfolio
            value = app.LongpositionportfolioEditField.Value;
            app.NetpositionEditField.Value = value -...
                app.ShortpositionportfolioEditField.Value;
            portfolio.Long.Initial = value/1000000;
            portfolio.Net.Initial = portfolio.Long.Initial - portfolio.Short.Initial;
        end

        % Value changed function: ShortpositionportfolioEditField
        function ShortpositionportfolioEditFieldValueChanged(app, event)
            global portfolio
            value = app.ShortpositionportfolioEditField.Value;
            app.NetpositionEditField.Value = app.LongpositionportfolioEditField.Value - value;
            portfolio.Short.Initial = value/1000000;
            portfolio.Net.Initial = portfolio.Long.Initial - portfolio.Short.Initial;
        end

        % Value changed function: ActualLongFinalEditField
        function ActualLongFinalEditFieldValueChanged(app, event)
            value = app.ActualLongFinalEditField.Value;
            app.ActualNetFinalEditField.Value = value - app.ActualShortFinalEditField.Value;
        end

        % Value changed function: ActualShortFinalEditField
        function ActualShortFinalEditFieldValueChanged(app, event)
            value = app.ActualShortFinalEditField.Value;
            app.ActualNetFinalEditField.Value = app.ActualLongFinalEditField.Value - value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Dashboard = uifigure('Visible', 'off');
            app.Dashboard.Name = 'Risk Mamagement Dashboard';
            app.Dashboard.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Dashboard);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PortfoliosvalueVaRSliderLabel = uilabel(app.LeftPanel);
            app.PortfoliosvalueVaRSliderLabel.Text = {'Portfolio''s value'; 'VaR (%)'};
            app.PortfoliosvalueVaRSlider = uislider(app.LeftPanel);
            app.PortfoliosvalueVaRSlider.ValueChangedFcn = createCallbackFcn(app, @PortfoliosvalueVaRSliderValueChanged, true);
            app.PortfoliosvalueVaRSlider.Value = 5;
            app.UpdategraphsButton = uibutton(app.LeftPanel, 'push');
            app.UpdategraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphS1, true);
            app.UpdategraphsButton.Text = {'Update'; 'graphs'};
            app.InitialvaluesPanel = uipanel(app.LeftPanel);
            app.InitialvaluesPanel.Title = 'Initial values';
            app.LongpositionportfolioEditFieldLabel = uilabel(app.InitialvaluesPanel);
            app.LongpositionportfolioEditFieldLabel.Text = {'Long position'; 'portfolio'};
            app.LongpositionportfolioEditField = uieditfield(app.InitialvaluesPanel, 'numeric');
            app.LongpositionportfolioEditField.Limits = [0 Inf];
            app.LongpositionportfolioEditField.ValueDisplayFormat = '%.0f';
            app.LongpositionportfolioEditField.ValueChangedFcn = createCallbackFcn(app, @LongpositionportfolioEditFieldValueChanged, true);
            app.LongpositionportfolioEditField.Value = 110000000;
            app.ShortpositionportfolioLabel = uilabel(app.InitialvaluesPanel);
            app.ShortpositionportfolioLabel.Text = {'Short position'; 'portfolio'};
            app.ShortpositionportfolioEditField = uieditfield(app.InitialvaluesPanel, 'numeric');
            app.ShortpositionportfolioEditField.Limits = [0 Inf];
            app.ShortpositionportfolioEditField.ValueDisplayFormat = '%.0f';
            app.ShortpositionportfolioEditField.ValueChangedFcn = createCallbackFcn(app, @ShortpositionportfolioEditFieldValueChanged, true);
            app.ShortpositionportfolioEditField.Value = 100000000;
            app.NetpositionEditFieldLabel = uilabel(app.InitialvaluesPanel);
            app.NetpositionEditFieldLabel.Text = 'Net position';
            app.NetpositionEditField = uieditfield(app.InitialvaluesPanel, 'numeric');
            app.NetpositionEditField.ValueDisplayFormat = '%.0f';
            app.NetpositionEditField.Editable = 'off';
            app.NetpositionEditField.Value = 10000000;
            app.PortfolioValueVarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PortfolioValueVarEditField.Limits = [0 50];
            app.PortfolioValueVarEditField.RoundFractionalValues = 'on';
            app.PortfolioValueVarEditField.ValueDisplayFormat = '%11.2g';
            app.PortfolioValueVarEditField.ValueChangedFcn = createCallbackFcn(app, @PortfolioValueVarEditFieldValueChanged, true);
            app.PortfolioValueVarEditField.Value = 5;
            app.EllipseToleranceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EllipseToleranceEditField.Limits = [50 100];
            app.EllipseToleranceEditField.RoundFractionalValues = 'on';
            app.EllipseToleranceEditField.ValueDisplayFormat = '%11.2g';
            app.EllipseToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @EllipseToleranceEditFieldValueChanged, true);
            app.EllipseToleranceEditField.Value = 80;
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SelectpositionDropDownLabel = uilabel(app.LeftPanel);
            app.SelectpositionDropDownLabel.Text = 'Select position';
            app.SelectpositionDropDown = uidropdown(app.LeftPanel);
            app.SelectpositionDropDown.Items = {'Net', 'Long', 'Short'};
            app.SelectpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectpositionDropDownValueChanged, true);
            app.SelectpositionDropDown.Value = 'Net';
            app.EllipsetoleranceLabel = uilabel(app.LeftPanel);
            app.EllipsetoleranceLabel.Text = {'Ellipse'; 'tolerance (%)'};
            app.EllipsetoleranceSlider = uislider(app.LeftPanel);
            app.EllipsetoleranceSlider.ValueChangedFcn = createCallbackFcn(app, @EllipsetoleranceSliderValueChanged, true);
            app.EllipsetoleranceSlider.Value = 80;
            app.NumberofsimulationsSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofsimulationsSpinnerLabel.Text = {'Number of'; 'simulations'};
            app.NumberofsimulationsSpinner = uispinner(app.LeftPanel);
            app.NumberofsimulationsSpinner.Step = 1000;
            app.NumberofsimulationsSpinner.Limits = [0 Inf];
            app.NumberofsimulationsSpinner.RoundFractionalValues = 'on';
            app.NumberofsimulationsSpinner.ValueDisplayFormat = '%d';
            app.NumberofsimulationsSpinner.Value = 10000;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ActualvaluesTab = uitab(app.TabGroup);
            app.ActualvaluesTab.Title = 'Actual values';
            app.LongpositionportfolioEditField_2Label = uilabel(app.ActualvaluesTab);
            app.LongpositionportfolioEditField_2Label.Text = {'Long position'; 'portfolio'};
            app.ActualLongFinalEditField = uieditfield(app.ActualvaluesTab, 'numeric');
            app.ActualLongFinalEditField.ValueDisplayFormat = '%11.16g';
            app.ActualLongFinalEditField.ValueChangedFcn = createCallbackFcn(app, @ActualLongFinalEditFieldValueChanged, true);
            app.ActualLongFinalEditField.Value = 111000000;
            app.ShortpositionportfolioEditField_2Label = uilabel(app.ActualvaluesTab);
            app.ShortpositionportfolioEditField_2Label.Text = {'Short position'; 'portfolio'};
            app.ActualShortFinalEditField = uieditfield(app.ActualvaluesTab, 'numeric');
            app.ActualShortFinalEditField.RoundFractionalValues = 'on';
            app.ActualShortFinalEditField.ValueDisplayFormat = '%11.16g';
            app.ActualShortFinalEditField.ValueChangedFcn = createCallbackFcn(app, @ActualShortFinalEditFieldValueChanged, true);
            app.ActualShortFinalEditField.Value = 100900000;
            app.NetpositionEditField_2Label = uilabel(app.ActualvaluesTab);
            app.NetpositionEditField_2Label.Text = 'Net position';
            app.ActualNetFinalEditField = uieditfield(app.ActualvaluesTab, 'numeric');
            app.ActualNetFinalEditField.RoundFractionalValues = 'on';
            app.ActualNetFinalEditField.ValueDisplayFormat = '%11.16g';
            app.ActualNetFinalEditField.Editable = 'off';
            app.ActualNetFinalEditField.Value = 10100000;
            app.ActualCashflowsTab = uitab(app.TabGroup);
            app.ActualCashflowsTab.Title = 'Actual Cashflows';
            app.LongpositionmaturitiesEditFieldLabel = uilabel(app.ActualCashflowsTab);
            app.LongpositionmaturitiesEditFieldLabel.Text = {'Long position'; 'maturities'};
            app.LongpositionmaturitiesEditField = uieditfield(app.ActualCashflowsTab, 'numeric');
            app.LongpositionmaturitiesEditField.ValueDisplayFormat = '%11.0f';
            app.LongpositionmaturitiesEditField.Value = 10500000;
            app.ShortpositionmaturitiesEditFieldLabel = uilabel(app.ActualCashflowsTab);
            app.ShortpositionmaturitiesEditFieldLabel.Text = {'Short position'; 'maturities'};
            app.ShortpositionmaturitiesEditField = uieditfield(app.ActualCashflowsTab, 'numeric');
            app.ShortpositionmaturitiesEditField.ValueDisplayFormat = '%11.0f';
            app.ShortpositionmaturitiesEditField.Value = 9200000;
            app.OpenedlongpositionsEditFieldLabel = uilabel(app.ActualCashflowsTab);
            app.OpenedlongpositionsEditFieldLabel.Text = {'Opened long'; 'positions'};
            app.OpenedlongpositionsEditField = uieditfield(app.ActualCashflowsTab, 'numeric');
            app.OpenedlongpositionsEditField.ValueDisplayFormat = '%11.0f';
            app.OpenedlongpositionsEditField.Value = 10600000;
            app.OpenedshortpositionLabel = uilabel(app.ActualCashflowsTab);
            app.OpenedshortpositionLabel.Text = {'Opened short'; 'position'};
            app.OpenedshortpositionEditField = uieditfield(app.ActualCashflowsTab, 'numeric');
            app.OpenedshortpositionEditField.ValueDisplayFormat = '%11.0f';
            app.OpenedshortpositionEditField.Value = 9500000;
            app.RightPanel = uipanel(app.GridLayout);
            app.Hist1 = uiaxes(app.RightPanel);
            title(app.Hist1, {'Simulated portfolio value distribution'; ''})
            xlabel(app.Hist1, 'Value - (Million USD)')
            ylabel(app.Hist1, '')
            app.S_ClosedCF = uiaxes(app.RightPanel);
            title(app.S_ClosedCF, {'Portfolio valuation V.s. Cashflows'; 'driven by closed positions'})
            xlabel(app.S_ClosedCF, 'Portfolio Valuation - (Million USD)')
            ylabel(app.S_ClosedCF, 'Cashflows - (Million USD)')
            app.S_NetCF = uiaxes(app.RightPanel);
            title(app.S_NetCF, {'Portfolio valuation V.s. Cashflows'; 'driven by opened positions'})
            xlabel(app.S_NetCF, 'Portfolio Valuation - (Million USD)')
            ylabel(app.S_NetCF, 'Cashflows - (Million USD)')
            app.PortfolioperformancePanel = uipanel(app.RightPanel);
            app.PortfolioperformancePanel.Title = 'Portfolio performance';
            app.ActualvalueEditFieldLabel = uilabel(app.PortfolioperformancePanel);
            app.ActualvalueEditFieldLabel.Text = 'Actual value';
            app.ActualvalueEditField = uieditfield(app.PortfolioperformancePanel, 'numeric');
            app.ActualvalueEditField.ValueDisplayFormat = '%11.0f';
            app.ActualvalueEditField.Editable = 'off';
            app.ExpectedvalueMillionUSDEditFieldLabel = uilabel(app.PortfolioperformancePanel);
            app.ExpectedvalueMillionUSDEditFieldLabel.Text = {'Expected value'; '(Million USD)'};
            app.ExpectedvalueMillionUSDEditField = uieditfield(app.PortfolioperformancePanel, 'numeric');
            app.ExpectedvalueMillionUSDEditField.ValueDisplayFormat = '%11.0f';
            app.ExpectedvalueMillionUSDEditField.Editable = 'off';
            app.ActualpercentileEditFieldLabel = uilabel(app.PortfolioperformancePanel);
            app.ActualpercentileEditFieldLabel.Text = 'Actual percentile';
            app.ActualpercentileEditField = uieditfield(app.PortfolioperformancePanel, 'numeric');
            app.ActualpercentileEditField.ValueDisplayFormat = '%11.2f';
            app.ActualpercentileEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Dashboard.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DashBoardV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Dashboard)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Dashboard)
        end
    end
end